classdef ManipulatorX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ENDEFFECTORPanel      matlab.ui.container.Panel
        Yaw_EE                matlab.ui.control.NumericEditField
        YawEditFieldLabel     matlab.ui.control.Label
        Roll_EE               matlab.ui.control.NumericEditField
        RollEditFieldLabel    matlab.ui.control.Label
        Pitch_EE              matlab.ui.control.NumericEditField
        PitchEditFieldLabel   matlab.ui.control.Label
        Z_EE                  matlab.ui.control.NumericEditField
        PzEditFieldLabel      matlab.ui.control.Label
        Y_EE                  matlab.ui.control.NumericEditField
        PyEditFieldLabel      matlab.ui.control.Label
        X_EE                  matlab.ui.control.NumericEditField
        PxEditFieldLabel      matlab.ui.control.Label
        FORWARDPanel          matlab.ui.container.Panel
        Clear                 matlab.ui.control.Button
        RESETButton           matlab.ui.control.Button
        Theta4degSliderLabel  matlab.ui.control.Label
        FORWARDButton         matlab.ui.control.Button
        Theta4EditField       matlab.ui.control.NumericEditField
        Theta3EditField       matlab.ui.control.NumericEditField
        Theta2EditField       matlab.ui.control.NumericEditField
        Theta1EditField       matlab.ui.control.NumericEditField
        Theta4Slider          matlab.ui.control.Slider
        Theta3Slider          matlab.ui.control.Slider
        Theta3degLabel        matlab.ui.control.Label
        Theta2Slider          matlab.ui.control.Slider
        Theta2degSliderLabel  matlab.ui.control.Label
        Theta1Slider          matlab.ui.control.Slider
        Theta1degSliderLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
%% -------- Ma trận biến đổi và tham số cơ bản của robot --------

   % Khai báo Homogeneous transform
        T01 = zeros(4);           % Ma trận biến đổi thuận nhất RF1 => RF0 
        T12 = zeros(4);           % Ma trận biến đổi thuận nhất RF2 => RF1
        T23 = zeros(4);           % Ma trận biến đổi thuận nhất RF3 => RF2
        T34 = zeros(4);           % Ma trận biến đổi thuận nhất RF4 => RF3

        T02 = zeros(4);           % Ma trận biến đổi thuận nhất RF2 => RF0
        T03 = zeros(4);           % Ma trận biến đổi thuận nhất RF3 => RF0
        T04 = zeros(4);           % Ma trận biến đổi thuận nhất RF4 => RF0

        eulerAngles = []; 

        % Tham số D-H
        theta = [   0       pi/2      -pi/2       0   ];
        d     = [   77      0      0       0   ];
        a     = [   0       130    124     126 ];
        Alpha = [   pi/2    0       0       0  ]; 

        % -------- Góc quay của các khớp --------
        old_theta = [0, 0, 0, 0];           % Góc quay trước đó của các khớp
        new_theta = [0, 0, 0, 0];           % Góc quay hiện tại của các khớp
        % theta1 = 0; 
        % theta2 = 0; 
        % theta3 = 0;
        % theta4 = 0;
        % Ma trận xoay theo trục y 
        Rotate_Y = [cos(pi/2)       0       sin(pi/2)       0   ; 
                    0               1       0               0   ; 
                    -sin(pi/2)      0       cos(pi/2)       0   ; 
                    0               0       0               1  ];

        % -------- Các trục và nhãn cho (base), khớp và end-effector --------
    % Trục tọa độ và label của base             % Trục tọa độ và label của khớp 1
    base_X;             base_Xlabel;            joint1_X;           joint1_Xlabel; 
    base_Y;             base_Ylabel;            joint1_Y;           joint1_Ylabel;
    base_Z;             base_Zlabel;            joint1_Z;           joint1_Zlabel;  

    % Trục tọa độ và label của khớp 2           % Trục tọa độ và label của khớp 3
    joint2_X;              joint2_Xlabel;       joint3_X;           joint3_Xlabel; 
    joint2_Y;              joint2_Ylabel;       joint3_Y;           joint3_Ylabel;
    joint2_Z;              joint2_Zlabel;       joint3_Z;           joint3_Zlabel;
    
    % Trục tọa độ và label của khớp 4           % Trục tọa độ và label của end-effector
    joint4_X;           joint4_Xlabel;          endEffector_X;      endEffector_Xlabel;
    joint4_Y;           joint4_Ylabel;          endEffector_Y;      endEffector_Ylabel;
    joint4_Z;           joint4_Zlabel;          endEffector_Z;      endEffector_Zlabel; 
    
    Joint1Angle;Joint2Angle;Joint3Angle;Joint4Angle
    robotPath; 


    end
    
    
    methods (Access = private)
 
        function results = Denavit_Hartenberg_Matrix(app, n)
        %% ====== Ma trận biến đổi Denavit-Hartenberg =======
         results = [cos(app.theta(n)),  -cos(app.Alpha(n))*sin(app.theta(n)), sin(app.Alpha(n))*sin(app.theta(n)), app.a(n)*cos(app.theta(n));
                  sin(app.theta(n)),  cos(app.Alpha(n))*cos(app.theta(n)), -sin(app.Alpha(n))*cos(app.theta(n)), app.a(n)*sin(app.theta(n));
                         0,                    sin(app.Alpha(n)),               cos(app.Alpha(n)),                     app.d(n);
                         0,                            0,                               0,                                1                 ];
        end
        
        function UpdateRobot(app)

%% ====== Update Robot =======

        % Xóa hình ảnh robot cũ trên trục tọa độ
        hold(app.UIAxes, "off");

        % Các ma trận biến đổi Denavit-Hartenberg
                app.T01 = Denavit_Hartenberg_Matrix(app,1);
                app.T12 = Denavit_Hartenberg_Matrix(app,2);
                app.T23 = Denavit_Hartenberg_Matrix(app,3);
                app.T34 = Denavit_Hartenberg_Matrix(app,4);
        
                app.T02 = app.T01*app.T12;          
                app.T03 = app.T01*app.T12*app.T23;       
                app.T04 = app.T01*app.T12*app.T23*app.T34;        


%% ------ Vẽ phần đế (Base) và Khớp Trục 0 (Joint 0) ------
        % Tạo hình trụ cho phần đế
        [X, Y, Z] = cylinder(40, 100); % Bán kính 40, 100 phân đoạn
        Z = Z * 40;                  % Chiều cao phần đế
        surf(app.UIAxes, X, Y, Z, 'FaceColor', 'r'); % Vẽ phần đế
        hold(app.UIAxes, "on");
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'r', 'EdgeColor', 'none'); % Mặt đáy
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'r', 'EdgeColor', 'none'); % Mặt trên
        
        % Tạo hình trụ cho Khớp 0
        [X, Y, Z] = cylinder(10, 100); % Bán kính 10, 100 phân đoạn
        Z = Z * 77;                  % Chiều cao Khớp 0
        surf(app.UIAxes, X, Y, Z, 'FaceColor', 'r'); % Vẽ Khớp 0
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'r'); % Mặt đáy
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'r'); % Mặt trên

%% --------------- Vẽ Joint 1  --------------- 
        % Tạo hình trụ cho Joint 1
        [X, Y, Z] = cylinder(20, 100); % Bán kính 20, 50 phân đoạn
        Z = Z * 60 - 30 ;               % Chiều cao và dịch trục Z
        
        % Biến đổi DH và cập nhật tọa độ
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
        A_new = app.T01 * A; B_new = app.T01 * B;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);
        
        % Vẽ Joint 1
        surf(app.UIAxes, X, Y, Z, 'FaceColor', 'r');
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'r'); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'r'); % Mặt đáy trên

%% --------------- Vẽ Joint 2 --------------- 
        % Tạo hình trụ cho Joint 2
        [X, Y, Z] = cylinder(20, 100); % Bán kính 20, 100 phân đoạn
        Z = Z * 60 - 30 ;               % Chiều cao và dịch trục Z
        
        % Biến đổi DH và cập nhật tọa độ
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
        A_new = app.T02 * A; B_new = app.T02 * B;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);
        
        % Vẽ Joint 2
        surf(app.UIAxes, X, Y, Z, 'FaceColor', 'r');
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'r'); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'r'); % Mặt đáy trên

%% --------------- Vẽ Joint 3 --------------- 
        % Tạo hình trụ cho Joint 3
        [X, Y, Z] = cylinder(20, 100); % Bán kính 20, 100 phân đoạn
        Z = Z * 60 - 30 ;               % Chiều cao và dịch trục Z
        
        % Biến đổi DH và cập nhật tọa độ
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
        A_new = app.T03 * A; B_new = app.T03 * B;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);
        
        % Vẽ Joint 2
        surf(app.UIAxes, X, Y, Z, 'FaceColor', 'r');
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'r'); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'r'); % Mặt đáy trên

   %% --------------- Vẽ End-effector --------------- 
    % Tạo end-effector
    x_e = [ 10,   50,   40,   30,   20,   10,   5,   10,   20,   30,   40,   50,   10];
    z_e = [-10,  -10,  -20,  -20,  -30,  -30,    0,   30,   30,   20,   20,   10,   10];
    
    y_e = [  5,    5,    5,    5,    5,    5,    5,    5,    5,    5,    5,    5,    5 ;...
            -5,   -5,   -5,   -5,   -5,   -5,   -5,   -5,   -5,   -5,   -5,   -5,   -5];        % Tạo hai mặt
    
    x_e = repmat(x_e, 2, 1); % Tạo ma trận 2 hàng từ x_e
    z_e = repmat(z_e, 2, 1); % Tạo ma trận 2 hàng từ z_e
    
    % Biến đổi DH cho effector
        A = [x_e(1, :); y_e(1, :); z_e(1, :); ones(1, size(x_e, 2))];   % Lưu các điểm mặt dưới theo vecto cột, 
        B = [x_e(2, :); y_e(2, :); z_e(2, :); ones(1, size(x_e, 2))];   % Lưu các điểm mặt trên theo vecto cột,
        A_new = app.T04 * A;        % Nhân các điểm của ma trận A với T04
        B_new = app.T04 * B;        % Nhân các điểm của ma trận B với T04


        x_e(1, :) = A_new(1, :);        y_e(1, :) = A_new(2, :);        % Cập nhật lại các tọa độ x y z
        x_e(2, :) = B_new(1, :);        y_e(2, :) = B_new(2, :);
        z_e(1, :) = A_new(3, :);        z_e(2, :) = B_new(3, :);
    
    % Vẽ effector
    surf(app.UIAxes, x_e, y_e, z_e, 'FaceColor', 'b');
    fill3(app.UIAxes, x_e(1, :), y_e(1, :), z_e(1, :), 'b'); % Mặt trên
    fill3(app.UIAxes, x_e(2, :), y_e(2, :), z_e(2, :), 'b'); % Mặt dưới

    % Vị trí điểm P
    x_endEffector = 50;
    y_endEffector = 0;
    z_endEffector = 0;
    % Tọa độ điểm P của end effector
    P_endEffector = [x_endEffector; y_endEffector; z_endEffector; 1];            
    % Nhân các điểm của ma trận P với T04
    P_endEffector = app.T04 * P_endEffector;                                       
    % Cập nhật lại các tọa độ x y z
    x_endEffector = P_endEffector(1, 1);                       
    y_endEffector = P_endEffector(2, 1);
    z_endEffector = P_endEffector(3, 1);
   
    % Hiển thị vị trí của effector lên box value
    app.X_EE.Value = x_endEffector;                
    app.Y_EE.Value = y_endEffector;
    app.Z_EE.Value = z_endEffector;

    % Thêm tọa độ mới vào đường đi
    [~, n] = size(app.robotPath);
    app.robotPath(1, n + 1) = x_endEffector;   % ban đầu khai báo biến robotPath, và biến n là số cột của biến robotPath
    app.robotPath(2, n + 1) = y_endEffector;   % cập nhật các giá trị x y z của end effector, đồng thời tăng số cột lên 1  
    app.robotPath(3, n + 1) = z_endEffector;   % để tạo cột cho các giá trị x y z tiếp theo    
   % Vẽ đường đi trên trục tọa độ UIAxes
    plot3(app.UIAxes, app.robotPath(1, :), app.robotPath(2, :), app.robotPath(3, :), 'LineWidth', 2, 'Color', 'b');


%% --------------- Vẽ Link 1 --------------- 
        % Tạo hình trụ cho Link 1
        [X, Y, Z] = cylinder(10);  % Bán kính 10
        Z = Z * 128 - 128;           % Chiều cao và dịch trục Z
        
        % Xoay quanh trục Y và biến đổi DH
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
        A_new = app.Rotate_Y * A; B_new = app.Rotate_Y * B;
        A_new = app.T02 * A_new; B_new = app.T02 * B_new;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);
        
        % Vẽ Link 1
        surf(app.UIAxes, X, Y, Z, 'FaceColor', 'r');
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'r'); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'r'); % Mặt đáy trên
%% --------------- Vẽ Link 2 --------------- 
        % Tạo hình trụ cho Link 2
        [X, Y, Z] = cylinder(10);  % Bán kính 10
        Z = Z * 124 - 124;           % Chiều cao và dịch trục Z
        
        % Xoay quanh trục Y và biến đổi DH
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
        A_new = app.Rotate_Y * A; B_new = app.Rotate_Y * B;
        A_new = app.T03 * A_new; B_new = app.T03 * B_new;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);
        
        % Vẽ Link 1
        surf(app.UIAxes, X, Y, Z, 'FaceColor', 'r');
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'r'); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'r'); % Mặt đáy trên
%% --------------- Vẽ Link 3 --------------- 
        % Tạo hình trụ cho Link 3
        [X, Y, Z] = cylinder(5);  % Bán kính 10
        Z = Z * 128 - 128;           % Chiều cao và dịch trục Z
        
        % Xoay quanh trục Y và biến đổi DH
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
        A_new = app.Rotate_Y * A; B_new = app.Rotate_Y * B;
        A_new = app.T04 * A_new; B_new = app.T04 * B_new;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);
        
        % Vẽ Link 1
        surf(app.UIAxes, X, Y, Z, 'FaceColor', 'r');
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'r'); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'r'); % Mặt đáy trên

%% ======= Tính toán góc Euler từ ma trận biến đổi T03 (3 góc quay theo trục x y z: roll ψ, pitch θ, yaw ϕ)
%---------------------------------------------------------------------
        %       [  cϕ * cθ     cϕ * sθ * sψ - sϕ * cψ      cϕ * sθ * cψ + sϕ * sψ        -   ]
        % T03 = |  sϕ * cθ     sϕ * sθ * sψ + cϕ * cψ      sϕ * sθ * cψ - cϕ * sψ        -   |
        %       |  -sθ         cθ * sψ                     cθ * cψ                       -   |
        %       [    0             0                         0                           1   ]
%---------------------------------------------------------------------
        
    cospitch = sqrt((app.T03(1, 1))^2 + (app.T03(2, 1))^2);     %cθ = sqrt[ (cϕ * cθ)^2 + (sϕ * cθ)^2 ]
    pitch = atan2(-app.T03(3, 1), cospitch);                    %sθ = -T03(3, 1)]
    
%---------------------------------------------------------------------
        % Tại pitch = pi/2 thì
        %       [  0      s(ψ - ϕ)      c(ψ - ϕ)        -   ]            [  r11      r12     r13       -   ]
        % T03 = |  0      c(ψ - ϕ)     -s(ψ - ϕ)        -   |   => T03 = |  r21      r22     r23       -   |
        %       |  1        0             0             -   |            |  r31      r32     r33       -   |
        %       [  0        0             0             1   ]            [   0        0       0        1   ]
%---------------------------------------------------------------------


    if pitch == pi / 2          % Trường hợp robot duỗi thẳng lên trên, vô số nghiệm yaw, chọn yaw = 0

        yaw = 0;                                                %sψ = r12
        roll = atan2(app.T03(1, 2), app.T03(2, 2));             %cψ = r22

%---------------------------------------------------------------------
        % Tại pitch = -pi/2 thì
        %       [  0     -s(ψ - ϕ)     -c(ψ - ϕ)        -   ]
        % T03 = |  0      c(ψ - ϕ)     -s(ψ - ϕ)        -   |
        %       |  1        0             0             -   |
        %       [  0        0             0             1   ]
%---------------------------------------------------------------------

    elseif pitch == -pi / 2

        yaw = 0;                                                %sψ = -r12
        roll = atan2(-app.T03(1, 2), app.T03(2, 2));            %cψ =  r22

    else
                                                                                %sϕ = r21/cθ
        yaw = atan2(app.T03(2, 1) / cos(pitch), app.T03(1, 1) / cos(pitch));    %cϕ = r11/cθ
        roll = atan2(app.T03(3, 2) / cos(pitch), app.T03(3, 3) / cos(pitch));   %sψ = r32/cθ
                                                                                %cψ = r33/cθ
    end

    app.eulerAngles = [roll pitch yaw] * (180 / pi); % Chuyển đổi từ radian sang độ

% Cập nhật giá trị góc Euler vào các ô nhập liệu
    app.Roll_EE.Value    = app.eulerAngles(1);
    app.Pitch_EE.Value   = app.eulerAngles(2);
    app.Yaw_EE.Value     = app.eulerAngles(3);
      
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            UpdateRobot(app)
            RESETButtonPushed(app)
        end

        % Button pushed function: FORWARDButton
        function FORWARDButtonPushed(app, event)
            
               app.old_theta(1) = app.theta(1);
               app.new_theta(1) = app.Theta1Slider.Value;
               app.Theta1EditField.Value = app.Theta1Slider.Value;
               app.new_theta(1) = deg2rad(app.new_theta(1));

               app.old_theta(2) = app.theta(2);
               app.new_theta(2) = app.Theta2Slider.Value;
               app.Theta2EditField.Value = app.Theta2Slider.Value;
               app.new_theta(2) = deg2rad(app.new_theta(2));

               app.old_theta(3) = app.theta(3);
               app.new_theta(3) = app.Theta3Slider.Value;
               app.Theta3EditField.Value = app.Theta3Slider.Value;
               app.new_theta(3) = deg2rad(app.new_theta(3));

               app.old_theta(4) = app.theta(4);
               app.new_theta(4) = app.Theta4Slider.Value;
               app.Theta4EditField.Value = app.Theta4Slider.Value;
               app.new_theta(4) = deg2rad(app.new_theta(4));

%% ================== Rời rạc hóa theta, phân tách thành 20 đoạn bằng nhau =======================
            DiscreteTheta1 = linspace(app.old_theta(1), app.new_theta(1), 20);       % Rời rạc hóa theta1
            DiscreteTheta2 = linspace(app.old_theta(2), app.new_theta(2), 20);       % Rời rạc hóa theta2
            DiscreteTheta3 = linspace(app.old_theta(3), app.new_theta(3), 20);       % Rời rạc hóa theta3
            DiscreteTheta4 = linspace(app.old_theta(4), app.new_theta(4), 20);       % Rời rạc hóa theta3

            for i=1:20
                app.theta(1) = DiscreteTheta1(i);
                app.theta(2) = DiscreteTheta2(i);
                app.theta(3) = DiscreteTheta3(i);
                app.theta(4) = DiscreteTheta4(i);
                app.UpdateRobot();
                pause(0.05);
            end
            
        end

        % Value changed function: Theta1Slider
        function Theta1SliderValueChanged(app, event)

               app.old_theta(1) = app.theta(1);
               app.new_theta(1) = app.Theta1Slider.Value;
               app.Theta1EditField.Value = app.Theta1Slider.Value;
               app.new_theta(1) = deg2rad(app.new_theta(1));

        end

        % Value changed function: Theta2Slider
        function Theta2SliderValueChanged(app, event)
            

               app.old_theta(2) = app.theta(2);
               app.new_theta(2) = app.Theta2Slider.Value;
               app.Theta2EditField.Value = app.Theta2Slider.Value;
               app.new_theta(2) = deg2rad(app.new_theta(2));

        end

        % Value changed function: Theta3Slider
        function Theta3SliderValueChanged(app, event)

               app.old_theta(3) = app.theta(3);
               app.new_theta(3) = app.Theta3Slider.Value;
               app.Theta3EditField.Value = app.Theta3Slider.Value;
               app.new_theta(3) = deg2rad(app.new_theta(3));

        end

        % Value changed function: Theta4Slider
        function Theta4SliderValueChanged(app, event)

               app.old_theta(4) = app.theta(4);
               app.new_theta(4) = app.Theta4Slider.Value;
               app.Theta4EditField.Value = app.Theta4Slider.Value;
               app.new_theta(4) = deg2rad(app.new_theta(4));
            
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            
               app.old_theta(1) = app.theta(1);
               app.new_theta(1) = 0;
               app.Theta1EditField.Value = app.new_theta(1);
               app.Theta1Slider.Value = app.new_theta(1);
               app.new_theta(1) = deg2rad(app.new_theta(1));

               app.old_theta(2) = app.theta(2);
               app.new_theta(2) = 90;
               app.Theta2EditField.Value = app.new_theta(2);
               app.Theta2Slider.Value = app.new_theta(2);
               app.new_theta(2) = deg2rad(app.new_theta(2));

               app.old_theta(3) = app.theta(3);
               app.new_theta(3) = -90;
               app.Theta3EditField.Value = app.new_theta(3);
               app.Theta3Slider.Value = app.new_theta(3);
               app.new_theta(3) = deg2rad(app.new_theta(3));

               app.old_theta(4) = app.theta(4);
               app.new_theta(4) = 0;
               app.Theta4EditField.Value = app.new_theta(4);
               app.Theta4Slider.Value = app.new_theta(4);
               app.new_theta(4) = deg2rad(app.new_theta(4));

 %% Rời rạc hóa theta, phân tách thành 20 đoạn bằng nhau
            DiscreteTheta1 = linspace(app.old_theta(1), app.new_theta(1), 20);       % Rời rạc hóa theta1
            DiscreteTheta2 = linspace(app.old_theta(2), app.new_theta(2), 20);       % Rời rạc hóa theta2
            DiscreteTheta3 = linspace(app.old_theta(3), app.new_theta(3), 20);       % Rời rạc hóa theta3
            DiscreteTheta4 = linspace(app.old_theta(4), app.new_theta(4), 20);       % Rời rạc hóa theta3

            for i=1:20
                app.theta(1) = DiscreteTheta1(i);
                app.theta(2) = DiscreteTheta2(i);
                app.theta(3) = DiscreteTheta3(i);
                app.theta(4) = DiscreteTheta4(i);
                app.UpdateRobot();
                pause(0.05);
            end
              
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
                 app.robotPath = [];
                 app.UpdateRobot();
        end

        % Value changed function: Theta1EditField
        function Theta1EditFieldValueChanged(app, event)
            app.old_theta(1) = app.theta(1);
            app.new_theta(1) = app.Theta1EditField.Value;
            app.Theta1Slider.Value = app.new_theta(1);
            app.new_theta(1) = deg2rad(app.new_theta(1));    
        end

        % Value changed function: Theta2EditField
        function Theta2EditFieldValueChanged(app, event)
            app.old_theta(2) = app.theta(2);
            app.new_theta(2) = app.Theta2EditField.Value;
            app.Theta2Slider.Value = app.new_theta(2);
            app.new_theta(2) = deg2rad(app.new_theta(2));    
        end

        % Value changed function: Theta3EditField
        function Theta3EditFieldValueChanged(app, event)
            app.old_theta(3) = app.theta(3);
            app.new_theta(3) = app.Theta3EditField.Value;
            app.Theta3Slider.Value = app.new_theta(3);
            app.new_theta(3) = deg2rad(app.new_theta(3));   
        end

        % Value changed function: Theta4EditField
        function Theta4EditFieldValueChanged(app, event)
            app.old_theta(4) = app.theta(4);
            app.new_theta(4) = app.Theta4EditField.Value;
            app.Theta4Slider.Value = app.new_theta(4);
            app.new_theta(4) = deg2rad(app.new_theta(4));    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Manipulator X')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-500 500];
            app.UIAxes.YLim = [-500 500];
            app.UIAxes.ZLim = [0 500];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-500 -400 -300 -200 -100 0 100 200 300 400 500];
            app.UIAxes.XTickLabel = {'-500'; '-400'; '-300'; '-200'; '-100'; '0'; '100'; '200'; '300'; '400'; '500'};
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-500 -400 -300 -200 -100 0 100 200 300 400 500];
            app.UIAxes.YTickLabel = {'-500'; '-400'; '-300'; '-200'; '-100'; '0'; '100'; '200'; '300'; '400'; '500'};
            app.UIAxes.ZTick = [0 100 200 300 400 500];
            app.UIAxes.ZTickLabel = {'0'; '100'; '200'; '300'; '400'; '500'};
            app.FORWARDPanel = uipanel(app.UIFigure);
            app.FORWARDPanel.Title = 'FORWARD';
            app.Theta1degSliderLabel = uilabel(app.FORWARDPanel);
            app.Theta1degSliderLabel.Text = {'Theta1'; '(deg)'};
            app.Theta1Slider = uislider(app.FORWARDPanel);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta1SliderValueChanged, true);
            app.Theta2degSliderLabel = uilabel(app.FORWARDPanel);
            app.Theta2degSliderLabel.Text = {'Theta2'; '(deg)'};
            app.Theta2Slider = uislider(app.FORWARDPanel);
            app.Theta2Slider.Limits = [-180 180];
            app.Theta2Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta2Slider.ValueChangedFcn = createCallbackFcn(app, @Theta2SliderValueChanged, true);
            app.Theta3degLabel = uilabel(app.FORWARDPanel);
            app.Theta3degLabel.Text = {'Theta3'; '(deg)'};
            app.Theta3Slider = uislider(app.FORWARDPanel);
            app.Theta3Slider.Limits = [-180 180];
            app.Theta3Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta3Slider.ValueChangedFcn = createCallbackFcn(app, @Theta3SliderValueChanged, true);
            app.Theta4Slider = uislider(app.FORWARDPanel);
            app.Theta4Slider.Limits = [-180 180];
            app.Theta4Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta4Slider.ValueChangedFcn = createCallbackFcn(app, @Theta4SliderValueChanged, true);
            app.Theta1EditField = uieditfield(app.FORWARDPanel, 'numeric');
            app.Theta1EditField.ValueDisplayFormat = '%.2f';
            app.Theta1EditField.ValueChangedFcn = createCallbackFcn(app, @Theta1EditFieldValueChanged, true);
            app.Theta2EditField = uieditfield(app.FORWARDPanel, 'numeric');
            app.Theta2EditField.ValueDisplayFormat = '%.2f';
            app.Theta2EditField.ValueChangedFcn = createCallbackFcn(app, @Theta2EditFieldValueChanged, true);
            app.Theta3EditField = uieditfield(app.FORWARDPanel, 'numeric');
            app.Theta3EditField.ValueDisplayFormat = '%.2f';
            app.Theta3EditField.ValueChangedFcn = createCallbackFcn(app, @Theta3EditFieldValueChanged, true);
            app.Theta4EditField = uieditfield(app.FORWARDPanel, 'numeric');
            app.Theta4EditField.ValueDisplayFormat = '%.2f';
            app.Theta4EditField.ValueChangedFcn = createCallbackFcn(app, @Theta4EditFieldValueChanged, true);
            app.FORWARDButton = uibutton(app.FORWARDPanel, 'push');
            app.FORWARDButton.ButtonPushedFcn = createCallbackFcn(app, @FORWARDButtonPushed, true);
            app.FORWARDButton.Text = 'FORWARD';
            app.Theta4degSliderLabel = uilabel(app.FORWARDPanel);
            app.Theta4degSliderLabel.Text = {'Theta4'; '(deg)'};
            app.RESETButton = uibutton(app.FORWARDPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.Clear = uibutton(app.FORWARDPanel, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = 'CLEAR';
            app.ENDEFFECTORPanel = uipanel(app.UIFigure);
            app.ENDEFFECTORPanel.Title = 'END - EFFECTOR';
            app.PxEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.PxEditFieldLabel.Text = 'Px';
            app.X_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.X_EE.ValueDisplayFormat = '%.2f';
            app.PyEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.PyEditFieldLabel.Text = 'Py';
            app.Y_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Y_EE.ValueDisplayFormat = '%.2f';
            app.PzEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.PzEditFieldLabel.Text = 'Pz';
            app.Z_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Z_EE.ValueDisplayFormat = '%.2f';
            app.PitchEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.Pitch_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.RollEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.Roll_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.YawEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.Yaw_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManipulatorX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end